classdef colorsplitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSplitter        matlab.ui.Figure
        InputDirectoryButton   matlab.ui.control.Button
        OutputDirectoryButton  matlab.ui.control.Button
        ColourChannelLabel     matlab.ui.control.Label
        ColourChannelDropDown  matlab.ui.control.DropDown
        ColourChannelSplitter21000Label  matlab.ui.control.Label
        SplititGoodButton      matlab.ui.control.Button
    end


    properties (Access = private)
        inputdir
        outputdir
        chan % Description
        color
    end


    methods (Access = private)

        % Button pushed function: InputDirectoryButton
        function InputDirectoryButtonPushed(app, event)
            app.inputdir = uigetdir('I:\CancerCare\Physics1\14 Projects\35_EPID-based Small Field Dosimetry\Data\');
            
        end

        % Button pushed function: OutputDirectoryButton
        function OutputDirectoryButtonPushed(app, event)
            app.outputdir = uigetdir('I:\CancerCare\Physics1\14 Projects\35_EPID-based Small Field Dosimetry\Data\');
        end

        % Value changed function: ColourChannelDropDown
        function ColourChannelDropDownValueChanged(app, event)
            app.color = app.ColourChannelDropDown.Value;
            
            switch app.color
                case 'red'
                    app.chan = 1;
                case 'green'
                    app.chan = 2;
                case 'blue'
                    app.chan = 3;
            end


        end

        % Button pushed function: SplititGoodButton
        function SplititGoodButtonPushed(app, event)
            
            channelSplitBatch(app, app.inputdir, app.outputdir, app.chan);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSplitter = uifigure;
            app.ChannelSplitter.Name = 'UI Figure';
            app.InputDirectoryButton = uibutton(app.ChannelSplitter, 'push');
            app.InputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @InputDirectoryButtonPushed, true);
            app.InputDirectoryButton.Text = 'Input Directory';
            app.OutputDirectoryButton = uibutton(app.ChannelSplitter, 'push');
            app.OutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryButtonPushed, true);
            app.OutputDirectoryButton.Text = 'Output Directory';
            app.ColourChannelLabel = uilabel(app.ChannelSplitter);
            app.ColourChannelLabel.Text = 'Colour Channel:';
            app.ColourChannelDropDown = uidropdown(app.ChannelSplitter);
            app.ColourChannelDropDown.Items = {'Red', 'Green', 'Blue'};
            app.ColourChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourChannelDropDownValueChanged, true);
            app.ColourChannelDropDown.Value = 'Red';
            app.ColourChannelSplitter21000Label = uilabel(app.ChannelSplitter);
            app.ColourChannelSplitter21000Label.Text = 'Colour Channel Splitter 21000';
            app.SplititGoodButton = uibutton(app.ChannelSplitter, 'push');
            app.SplititGoodButton.ButtonPushedFcn = createCallbackFcn(app, @SplititGoodButtonPushed, true);
            app.SplititGoodButton.Text = 'Split it Good';
        end
    end

    methods (Access = public)

        % Construct app
        function app = colorsplitter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelSplitter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSplitter)
        end
    end
end