classdef enterDoses < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalDoseEntry       matlab.ui.Figure
        CalDoseTable       matlab.ui.control.Table
        AcceptDosesButton  matlab.ui.control.Button
        CloseButton        matlab.ui.control.Button
    end


    properties (Access = private)
        filmProcessing % Main App calling this enter doses dialog box.
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filmProcessingApp, calibrationFiles)
                       
            app.filmProcessing = filmProcessingApp;
            app.CalDoseTable.Data = calibrationFiles;       
            
            
            %dp = uidatepicker(app.Panel, 'DisplayFormat', 'dd-MM-yyyy');
            %dp.Position(1:2) = [0 0];
            
        end

        % Button pushed function: AcceptDosesButton
        function AcceptDosesButtonPushed(app, event)
            app.CalDoseTable.BackgroundColor = [237/255 241/255 255/255];
            app.CalDoseTable.ForegroundColor = [200/255 200/255 200/255];
            
            acceptCalDoses(app.filmProcessing, app.CalDoseTable.Data); % send data to main app
                      
        end

        % Callback function: CalDoseEntry, CloseButton
        function CalDoseEntryCloseRequest(app, event)
                       
            
            % Delete this dialogue box
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalDoseEntry = uifigure;
            app.CalDoseEntry.Name = 'Enter Dose Values';
            app.CalDoseEntry.CloseRequestFcn = createCallbackFcn(app, @CalDoseEntryCloseRequest, true);
            app.CalDoseTable = uitable(app.CalDoseEntry);
            app.CalDoseTable.ColumnName = {'Image Name'; 'Dose (cgy)'};
            app.CalDoseTable.RowName = {};
            app.CalDoseTable.ColumnEditable = [false true];
            app.AcceptDosesButton = uibutton(app.CalDoseEntry, 'push');
            app.AcceptDosesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptDosesButtonPushed, true);
            app.AcceptDosesButton.Text = 'Accept Doses';
            app.CloseButton = uibutton(app.CalDoseEntry, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CalDoseEntryCloseRequest, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = enterDoses(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalDoseEntry)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalDoseEntry)
        end
    end
end