classdef filmProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilmProcessing                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalibrateFilmsTab              matlab.ui.container.Tab
        CreateNetODFilesPanel          matlab.ui.container.Panel
        SelectPrescansFolderButton     matlab.ui.control.Button
        SelectPostscansFolderButton    matlab.ui.control.Button
        SelectNetODFolderButton        matlab.ui.control.Button
        EditField_PreScanFolder        matlab.ui.control.EditField
        EditField_PostScanFolder       matlab.ui.control.EditField
        EditField_NetODFolder          matlab.ui.control.EditField
        GenerateNetODFilesButton       matlab.ui.control.Button
        UseexistingdividedfilesCheckBox  matlab.ui.control.CheckBox
        NetODHelpButton                matlab.ui.control.Button
        CalibrateDosePanel             matlab.ui.container.Panel
        SelectCalibrationImagesButton  matlab.ui.control.Button
        UIAxes_CalCurve                matlab.ui.control.UIAxes
        SaveCalibrationButton          matlab.ui.control.Button
        EnterCalibrationDosesButton    matlab.ui.control.Button
        PolyOrderDropDownLabel         matlab.ui.control.Label
        PolyOrderDropDown              matlab.ui.control.DropDown
        CalibrateDoseHelpButton        matlab.ui.control.Button
        DoseTab                        matlab.ui.container.Tab
        CreateDoseFilesPanel           matlab.ui.container.Panel
        GenerateDoseFilesButton        matlab.ui.control.Button
        UseothercalibrationfilesCheckBox  matlab.ui.control.CheckBox
        NetODHelpButton_2              matlab.ui.control.Button
        DoseDirectory                  matlab.ui.control.Label
        OutputFactorsPanel             matlab.ui.container.Panel
        OutputFactorsHelpButton        matlab.ui.control.Button
        SelectExcelFileButton          matlab.ui.control.Button
        ROIRadiusmmEditFieldLabel      matlab.ui.control.Label
        ROIRadiusmmEditField           matlab.ui.control.NumericEditField
        ExtractOFTab                   matlab.ui.container.Tab
        FILMPROCESSINGESPRESSOEDITIONLabel  matlab.ui.control.Label
    end

%something else
    properties (Access = private)
        calibrationFiles        % Filenames of calibration films
        calibrationFilesPath    % Directory of calibration files               
        p                       % polyfit coefficients derived after calibration
        calidosestable          % Table of calibration doses and calibration filenames
        doses                   % Dose values used from calibration films to generate cal curve
        netOD                   % NetOD values used from calibration films to generate cal curve
        polyOrder               % Which order polynomial to use
    end

    methods (Access = public)
        
        
        function acceptCalDoses(app, calibrationTable)
            % The uitable used to accept entry of calibration dose values fomr the user 
            % returns the dose values and matched filenames to the main program via this function.
            % This function also calls the calibration curve and assigns the polyfit coefficients.
            % and the netOD to the global variables.
            
            
            app.calidosestable = calibrationTable;
            
            if numel(app.calidosestable) > 4
                app.doses                               = [app.calidosestable{:,2}];
                
                % Print out the doses in the text field
%                 dosesstr                                = sprintf('%.0f,' , app.doses);
%                 dosesstr                                = dosesstr(1:end-1);
%                 app.LabelCalibrationDoses.Text          = dosesstr;
                
                % Get the polynomial coefficient and the NetOD values used for cal curve
                if isempty(app.polyOrder)
                    app.polyOrder = 2; % Use 2nd order polynomial as defaulf is user hasn't selected what they want
                    [app.netOD, app.p]                      = calibration(app.calibrationFiles, app.calibrationFilesPath, app.doses, app.polyOrder);
                    plotCalCurve(app); % plot it
                else
                    [app.netOD, app.p]                      = calibration(app.calibrationFiles, app.calibrationFilesPath, app.doses, app.polyOrder);
                    plotCalCurve(app);
                end
                
                % Plot the calibration curve and measured data
                
                
            else
                errordlg('Doses are not valid. Try again.', 'Invalid Input')
            end
        end
        
    end

    methods (Access = private)
    
        function plotCalCurve(app)
            % Displays the calibration curve, the fit generated
            % and measured NetOD/delivered dose values.
                       
            x1      = [0:0.01:max(app.netOD)+0.2];
            y1      = polyval(app.p, x1);
            
            
            plot(app.UIAxes_CalCurve, app.netOD, app.doses, 'o')
            hold (app.UIAxes_CalCurve, 'on')
            plot(app.UIAxes_CalCurve,x1,y1)
            hold (app.UIAxes_CalCurve, 'off')
            
            CalCurveDetails = sprintf(['Polynomial Order: \n'...
                'Coefficients: \n\n']);


        end
        
    end


    methods (Access = private)

        % Button pushed function: SelectPrescansFolderButton
        function SelectPrescansFolderButtonPushed(app, event)
            PreScanFolder = uigetdir('\\nsccgosfs04\physics1$\14 Projects\35_EPID-based Small Field Dosimetry\Data', 'Select Prescan Folder');
            if PreScanFolder == 0
                return
            else
                app.EditField_PreScanFolder.Value = PreScanFolder;
            end
        end

        % Button pushed function: SelectPostscansFolderButton
        function SelectPostscansFolderButtonPushed(app, event)
            preScanFolder = app.EditField_PreScanFolder.Value;
            idcs   = strfind(preScanFolder,'\');
            if exist(preScanFolder, 'dir')
                newdir = preScanFolder(1:idcs(end)-1); 
                PostScanFolder = uigetdir(newdir, 'Select Postscan Folder');
            else 
                PostScanFolder = uigetdir('Select NetOD Folder');
            end
                
            if PostScanFolder == 0
                return
            else
               app.EditField_PostScanFolder.Value = PostScanFolder;
            end
        end

        % Button pushed function: SelectNetODFolderButton
        function SelectNetODFolderButtonPushed(app, event)
            preScanFolder = app.EditField_PreScanFolder.Value;
            idcs   = strfind(preScanFolder,'\');
            if exist(preScanFolder, 'dir')
                newdir = preScanFolder(1:idcs(end)-1); 
                NetODFolder = uigetdir(newdir, 'Select NetOD Folder');
               
            else
                NetODFolder = uigetdir('Select NetOD Folder');
            end
            
            if NetODFolder == 0
                return
            else
                app.EditField_NetODFolder.Value = NetODFolder;
            end
        end

        % Button pushed function: GenerateNetODFilesButton
        function GenerateNetODFilesButtonPushed(app, event)
            
            preDir  = app.EditField_PreScanFolder.Value;
            postDir = app.EditField_PostScanFolder.Value;
            outDir  = app.EditField_NetODFolder.Value;
            
            % Processes all the pre scans and post scans and returns netOD to the specified folder.
            netODBatch(app, preDir, postDir, outDir); 

        end

        % Button pushed function: SelectCalibrationImagesButton
        function SelectCalibrationImagesButtonPushed(app, event)
        
            
            % Select a series of calibration image files
            
            if exist(app.EditField_NetODFolder.Value, 'dir')==7 
                
                % If NetODFolder directory already selected start browsing at that location.
                NetODFolder = app.EditField_NetODFolder.Value;
                [caliFiles, caliPath, ~] = uigetfile({'*.tiff', 'Tiff Files'; '*.*', 'All Files'}, ...
                                                'Select Calibration Files', NetODFolder, 'MultiSelect', 'On');
            else
              
                [caliFiles, caliPath, ~] = uigetfile({'*.tiff', 'Tiff Files'; '*.*', 'All Files'}, ...
                                                'Select Calibration Files', 'MultiSelect', 'On');
            end


            if  isempty(caliFiles)
                % if no calibration files were selected just do nothing
                return;
            elseif isequal(caliFiles, 0) 
                % If user hits cancel in the selection box just hang.
                return;
            else
                % If there is valid calribration files then update the global variables
                % and then ask for some delivered doses.
                app.calibrationFiles             = caliFiles';
                app.calibrationFilesPath         = caliPath;
                EnterCalibrationDosesButtonPushed(app,event);
            end
               
        end

        % Button pushed function: EnterCalibrationDosesButton
        function EnterCalibrationDosesButtonPushed(app, event)
            
           
            % if calidosetable already exists from previous selection use previous calidosetable 
            % and re-run dose selection dialog box if calidosetable is empty
            % create a new dummuy calidose table and then re-run dose selection
                       
            if isempty(app.calibrationFiles)
                % if no calibration files are selected then launch the file selector.
                SelectCalibrationImagesButtonPushed(app,event); 
            elseif isempty(app.calidosestable)
                % if a dose table isn't already there create dummy dose table
                newCellCol              = num2cell(1:size(app.calibrationFiles,1))';
                app.calidosestable      = [app.calibrationFiles newCellCol];
                enterDoses(app, app.calidosestable);
            else
                % Launch dialogue box to enter doses with existing dose table.
                enterDoses(app, app.calidosestable);
            end
            
            
        end

        % Button pushed function: SaveCalibrationButton
        function SaveCalibrationButtonPushed(app, event)
            
            saveCal2Txt(app.p, app.doses, app.calibrationFiles, app.calibrationFilesPath)
                    
            
            % v = 3.2353E+03*v*v*v + 1.4884E+03*v*v + 1.0192E+03*v + 2.6828E-01
            % plot the measured values and polyfit
            
            
        end

        % Button pushed function: NetODHelpButton
        function NetODHelpButtonPushed(app, event)
            message = sprintf(['Generate NetOD Files\n 1) Rigid registration of prescans to postscans \n'...
                                    '2) Calculation and saving of NetOD files for further processing']);
            uialert(app.FilmProcessing, message, 'Help: Generate NetOD Files', 'Icon', 'info') 
        end

        % Button pushed function: CalibrateDoseHelpButton
        function CalibrateDoseHelpButtonPushed(app, event)
            
            message = sprintf(['Calibrate Dose \n 1) Select images to use for calibration \n'...
                ' 2) Enter the corresponding delivered doses in table \n'...
                ' 3) Select the polynomial order for calibration curve fitting \n'...
                ' 4) Adjust values as needed then Generate Dose Files']);
            
            uialert(app.FilmProcessing, message, 'Help: Generate Dose Files', 'Icon', 'info') 
        end

        % Value changed function: PolyOrderDropDown
        function PolyOrderDropDownValueChanged(app, event)
            app.polyOrder       = str2num(app.PolyOrderDropDown.Value);
            
            if isrow(app.doses)
                app.p               = polyfit(app.netOD, app.doses, app.polyOrder);
                plotCalCurve(app);
            end
            
        end

        % Button pushed function: GenerateDoseFilesButton
        function GenerateDoseFilesButtonPushed(app, event)
                       
            
            if exist(app.EditField_NetODFolder.Value, 'dir')==7 
                
                % If NetODFolder directory already selected start browsing at that location.
                startFolder = app.EditField_NetODFolder.Value;
                [nODfiles, path, ~] = uigetfile({'*.tiff', 'Tiff Files'; '*.*', 'All Files'}, ...
                                                'Select NetOD images to convert to Dose', startFolder, 'MultiSelect', 'On');
            else
              
                [nODfiles, path, ~] = uigetfile({'*.tiff', 'Tiff Files'; '*.*', 'All Files'}, ...
                                                'Select NetOD images to convert to Dose', 'MultiSelect', 'On');
            end
           
                     
            if  isempty(nODfiles)
                % if no NetOD files were selected just do nothing
                return;
            elseif isequal(nODfiles, 0) 
                % If user hits cancel in the selection box just hang.
                return;
            else
                % If there is valid calibration curve then convert to doses
                % and then ask for some delivered doses.
                if isempty(app.p)
                    message = "No calibration coefficients yet";
                    uialert(app.FilmProcessing, message, 'Values missing');
                else 
                    dosedir = netOD2dose(nODfiles, path, app.polyOrder, app.p);
                    app.DoseDirectory.Text = dosedir;
                end
            end
        end

        % Button pushed function: OutputFactorsHelpButton
        function OutputFactorsHelpButtonPushed(app, event)
            message = sprintf(['Output Factors\n 1) Select excel file describing measurements \n'...
                                    '2) Must have the following named ranges: \n' ...
                                        'Meas_Date, Data_Dir, LinacName, MLC_Data, Norm_Data, Cone_Data']);
            uialert(app.FilmProcessing, message, 'Help: Extract Output Factors', 'Icon', 'info') 
        end

        % Button pushed function: SelectExcelFileButton
        function SelectExcelFileButtonPushed(app, event)
            [excelfile, path, ~] = uigetfile({ '*.*', 'All Files'}, ...
                                                    'Select excel file describing film measurements');
            excelfile = fullfile(path, excelfile);
            [Cone_Data, MLC_Data, LinacName, Meas_Date, Norm_Data, Data_Dir] = filmexcelextract(excelfile);
           FOFCone_RP(Norm_Data, Cone_Data, LinacName, Meas_Date, Data_Dir);
          % FOFMLC_func(Norm_Data, MLC_Data, LinacName, Meas_Date, Data_Dir);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilmProcessing = uifigure;
            app.FilmProcessing.Name = 'FilmProcessing';
            app.TabGroup = uitabgroup(app.FilmProcessing);
            app.CalibrateFilmsTab = uitab(app.TabGroup);
            app.CalibrateFilmsTab.Title = 'Calibrate Films';
            app.CreateNetODFilesPanel = uipanel(app.CalibrateFilmsTab);
            app.CreateNetODFilesPanel.Title = 'Create NetOD Files';
            app.SelectPrescansFolderButton = uibutton(app.CreateNetODFilesPanel, 'push');
            app.SelectPrescansFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPrescansFolderButtonPushed, true);
            app.SelectPrescansFolderButton.Text = 'Select Prescans Folder';
            app.SelectPostscansFolderButton = uibutton(app.CreateNetODFilesPanel, 'push');
            app.SelectPostscansFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPostscansFolderButtonPushed, true);
            app.SelectPostscansFolderButton.Text = 'Select Postscans Folder';
            app.SelectNetODFolderButton = uibutton(app.CreateNetODFilesPanel, 'push');
            app.SelectNetODFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNetODFolderButtonPushed, true);
            app.SelectNetODFolderButton.Text = 'Select NetOD Folder';
            app.EditField_PreScanFolder = uieditfield(app.CreateNetODFilesPanel, 'text');
            app.EditField_PreScanFolder.Value = 'Enter prescan folder path';
            app.EditField_PostScanFolder = uieditfield(app.CreateNetODFilesPanel, 'text');
            app.EditField_PostScanFolder.Value = 'Enter postscan folder path';
            app.EditField_NetODFolder = uieditfield(app.CreateNetODFilesPanel, 'text');
            app.EditField_NetODFolder.Value = 'Enter NetOD folder path';
            app.GenerateNetODFilesButton = uibutton(app.CreateNetODFilesPanel, 'push');
            app.GenerateNetODFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNetODFilesButtonPushed, true);
            app.GenerateNetODFilesButton.Text = 'Generate NetOD Files';
            app.UseexistingdividedfilesCheckBox = uicheckbox(app.CreateNetODFilesPanel);
            app.UseexistingdividedfilesCheckBox.Text = 'Use existing divided files';
            app.NetODHelpButton = uibutton(app.CreateNetODFilesPanel, 'push');
            app.NetODHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NetODHelpButtonPushed, true);
            app.NetODHelpButton.Icon = 'help_icon.png';
            app.NetODHelpButton.Text = '';
            app.CalibrateDosePanel = uipanel(app.CalibrateFilmsTab);
            app.CalibrateDosePanel.Title = 'Calibrate Dose';
            app.SelectCalibrationImagesButton = uibutton(app.CalibrateDosePanel, 'push');
            app.SelectCalibrationImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCalibrationImagesButtonPushed, true);
            app.SelectCalibrationImagesButton.Text = 'Select Calibration Images';
            app.UIAxes_CalCurve = uiaxes(app.CalibrateDosePanel);
            title(app.UIAxes_CalCurve, 'Calibration Curve')
            xlabel(app.UIAxes_CalCurve, 'Net OD')
            ylabel(app.UIAxes_CalCurve, 'Prescribed Dose (cGy)')
            app.SaveCalibrationButton = uibutton(app.CalibrateDosePanel, 'push');
            app.SaveCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButtonPushed, true);
            app.SaveCalibrationButton.Text = 'Save Calibration';
            app.EnterCalibrationDosesButton = uibutton(app.CalibrateDosePanel, 'push');
            app.EnterCalibrationDosesButton.ButtonPushedFcn = createCallbackFcn(app, @EnterCalibrationDosesButtonPushed, true);
            app.EnterCalibrationDosesButton.Text = 'Enter Calibration Doses';
            app.PolyOrderDropDownLabel = uilabel(app.CalibrateDosePanel);
            app.PolyOrderDropDownLabel.Text = 'Poly Order';
            app.PolyOrderDropDown = uidropdown(app.CalibrateDosePanel);
            app.PolyOrderDropDown.Items = {'2', '3', '4', '5'};
            app.PolyOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @PolyOrderDropDownValueChanged, true);
            app.PolyOrderDropDown.Value = '2';
            app.CalibrateDoseHelpButton = uibutton(app.CalibrateDosePanel, 'push');
            app.CalibrateDoseHelpButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateDoseHelpButtonPushed, true);
            app.CalibrateDoseHelpButton.Icon = 'help_icon.png';
            app.CalibrateDoseHelpButton.Text = '';
            app.DoseTab = uitab(app.TabGroup);
            app.DoseTab.Title = 'Dose';
            app.CreateDoseFilesPanel = uipanel(app.DoseTab);
            app.CreateDoseFilesPanel.Title = 'Create Dose Files';
            app.GenerateDoseFilesButton = uibutton(app.CreateDoseFilesPanel, 'push');
            app.GenerateDoseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDoseFilesButtonPushed, true);
            app.GenerateDoseFilesButton.Text = 'Generate Dose Files';
            app.UseothercalibrationfilesCheckBox = uicheckbox(app.CreateDoseFilesPanel);
            app.UseothercalibrationfilesCheckBox.Text = 'Use other calibration files';
            app.NetODHelpButton_2 = uibutton(app.CreateDoseFilesPanel, 'push');
            app.NetODHelpButton_2.Icon = 'help_icon.png';
            app.NetODHelpButton_2.Text = '';
            app.DoseDirectory = uilabel(app.CreateDoseFilesPanel);
            app.DoseDirectory.Text = 'Dose directory:';
            app.OutputFactorsPanel = uipanel(app.DoseTab);
            app.OutputFactorsPanel.Title = 'Output Factors';
            app.OutputFactorsHelpButton = uibutton(app.OutputFactorsPanel, 'push');
            app.OutputFactorsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFactorsHelpButtonPushed, true);
            app.OutputFactorsHelpButton.Icon = 'help_icon.png';
            app.OutputFactorsHelpButton.Text = '';
            app.SelectExcelFileButton = uibutton(app.OutputFactorsPanel, 'push');
            app.SelectExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFileButtonPushed, true);
            app.SelectExcelFileButton.Text = 'Select Excel File';
            app.ROIRadiusmmEditFieldLabel = uilabel(app.OutputFactorsPanel);
            app.ROIRadiusmmEditFieldLabel.Text = 'ROI Radius (mm)';
            app.ROIRadiusmmEditField = uieditfield(app.OutputFactorsPanel, 'numeric');
            app.ROIRadiusmmEditField.Value = 2;
            app.ExtractOFTab = uitab(app.TabGroup);
            app.ExtractOFTab.Title = 'Extract OF';
            app.FILMPROCESSINGESPRESSOEDITIONLabel = uilabel(app.FilmProcessing);
            app.FILMPROCESSINGESPRESSOEDITIONLabel.Text = 'F I L M   P R O C E S S I N G   -   E S P R E S S O   E D I T I O N ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = filmProcessing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilmProcessing)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilmProcessing)
        end
    end
end